classdef kadai1_yamaguchi_24966058 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        computeButton  matlab.ui.control.Button
        loadButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filename = uigetfile('/MATLAB Drive/logo_csse..png');
            fig = app.UIFigure;
            ax = uiaxes(fig,'Position',[62,275,272,157]);
            % read image
            im=imread(filename);
            % % plot image
            imshow(im,'Parent',ax);
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            % get filename
            filename = uigetfile ('/MATLAB Drive/logo_csse..png');
            fig = app.UIFigure;
            ax = uiaxes(fig,'Position',[83,99,251,165]);
            % read image
            im = imread(filename);
            % compute image
            imgray = rgb2gray(im);
            BW = imbinarize(imgray);
            %plot images
            imshow(BW*255,'Parent',ax)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kadai1_yamaguchi_24966058

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end